classdef pickCell_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        dat; % output of s2p
        prop; %property of cells [#cells,1] must be [0 1] (for now)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dat, prop)
            app.dat = initializeAfterLoading_test(dat);
            app.prop = prop;
            redraw_figure_test(app.UIAxes, app.dat, app.prop);
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            
            
% %             if ~isempty(event.Source.CurrentObject)% && isequal(event.Source.CurrentObject.Tag,'allData')
% %                 x = round(event.Source.CurrentPoint(2));
% %                 y  = round(event.Source.CurrentPoint(1));
% %                 %these are coordinates of the window, not the image panel
% %                 %(UIAxes)
% %                 
% %                 if x>=1 && y>=1 && x<=app.dat.cl.Lx && y<=app.dat.cl.Ly && app.dat.res.iclust(y,x)>0
% %                     app.dat.ichosen = app.dat.res.iclust(y, x);
% %                     ichosen = app.dat.ichosen;
% %                     
% %                     redraw_figure_test(app.UIAxes, app.dat);
% %                     
% %                     str = sprintf('cell #%d\n',ichosen);
% %                     title(app.UIAxes, str);
% %                 end
% %             end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            switch event.Key
                case 'q' %roi random color
                    % h.dat.map = 1;
                    app.dat.ichosen
                    %NG [~,app.dat] = redraw_figure_test(app.UIAxes, app.dat, app.prop);
                    redraw_figure_test(app.UIAxes, app.dat, app.prop);
                    
                case 'e' %mean image
                    %h.dat.map = 2;
                    app.dat.ichosen
                    %NG [~,app.dat] = redraw_meanimg_test(app.UIAxes, app.dat, app.prop);
                    redraw_meanimg_test(app.UIAxes, app.dat, app.prop);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'cell'; 'property'})
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pickCell_test(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end